classdef InterfazGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GraficasPanel                matlab.ui.container.Panel
        grafPricipal                 matlab.ui.control.UIAxes
        OpcionesPanel                matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        UITable2                     matlab.ui.control.Table
        NumPuntosEditField           matlab.ui.control.NumericEditField
        NumPuntosEditFieldLabel      matlab.ui.control.Label
        MaxDivisionesEditField       matlab.ui.control.NumericEditField
        MaxDivisionesEditFieldLabel  matlab.ui.control.Label
        Lado2EditField               matlab.ui.control.NumericEditField
        Lado2EditFieldLabel          matlab.ui.control.Label
        Lado1EditField               matlab.ui.control.NumericEditField
        Lado1EditFieldLabel          matlab.ui.control.Label
        LimpiarButton                matlab.ui.control.Button
        GraficarButton               matlab.ui.control.Button
    end


    methods (Access = public)
        function [vxO,vyO,vxClip,vyClip] = CellProliferation(app,X, Y, VoronoiBoundary, ...
                                                clip2boundary, showFigure)
if nargin < 3
    error('Parametros insuficientes.');
elseif nargin > 5
    error('Demasiados parametros');
end

if nargin == 3
    showIntersectionPlot = 0;

    showFigure = 1;
end

if nargin == 4
    showIntersectionPlot = 0;
    showFigure = 1;
end

if nargin == 5
    showIntersectionPlot = 0;
end
mapToolBoxLicense = 0;

%------------------------------------------------------------------------------------------------------------------------
% Generate voronoi points
[vxO,vyO] = voronoi(X,Y);
vx = vxO;
vy = vyO;

%------------------------------------------------------------------------------------------------------------------------
% LIMITES VORONOO
imX1 = VoronoiBoundary(1);
imX2 = VoronoiBoundary(2);
imY1 = VoronoiBoundary(3);
imY2 = VoronoiBoundary(4);

%% ------------------------------------------------------------------------------------------------------------------------
% Borde izquierdo de un rectángulo/cuadrado
[row_left,col_left] = find(vx < imX1);
leftEdgeVertsX = vx(:, col_left);
leftEdgeVertsY = vy(:, col_left);
xiLeft = []; yiLeft = [];

for i = 1:size(leftEdgeVertsX,2)
    if mapToolBoxLicense
        [xiLeftval, yiLeftval] = polyxpoly(leftEdgeVertsX(:,i), leftEdgeVertsY(:,i), [imX1 imX1], [imY1 imY2]);
    else
        [xiLeftval, yiLeftval] = linexline(app,leftEdgeVertsX(:,i), leftEdgeVertsY(:,i), [imX1 imX1], [imY1 imY2], showIntersectionPlot);
    end

    if isempty(xiLeftval) || isempty(yiLeftval)
        xiLeft(i) = NaN; yiLeft(i) = NaN; 
    else
        xiLeft(i) = xiLeftval;  yiLeft(i) = yiLeftval;
    end
end

if isempty(xiLeft) || isempty(yiLeft)
    row_left = [];
    col_left = [];
    xiLeft = [];
    yiLeft = [];
else
    [rowNaNLeft, colNaNLeft] = find(isnan(xiLeft));
    row_left(colNaNLeft) = [];
    col_left(colNaNLeft) = [];
    xiLeft(isnan(xiLeft)) = [];
    yiLeft(isnan(yiLeft)) = [];
end

%------------------------------------------------------------------------------------------------------------------------
% Borde derecho de un rectángulo/cuadrado
[row_right,col_right] = find(vx > imX2);
rightEdgeVertsX = vx(:, col_right);
rightEdgeVertsY = vy(:, col_right);
xiRight = []; yiRight = [];

for i = 1:size(rightEdgeVertsX,2)  
    if mapToolBoxLicense
        [xiRightval, yiRightval] = polyxpoly(rightEdgeVertsX(:,i), rightEdgeVertsY(:,i), [imX2 imX2], [imY1 imY2]);
    else
        [xiRightval, yiRightval] = linexline(app,rightEdgeVertsX(:,i), rightEdgeVertsY(:,i), [imX2 imX2], [imY1 imY2], showIntersectionPlot);
    end

    if isempty(xiRightval) || isempty(yiRightval)
        xiRight(i) = NaN; yiRight(i) = NaN; 
    else
        xiRight(i) = xiRightval;  yiRight(i) = yiRightval;
    end
end

if isempty(xiRight) || isempty(yiRight)
    row_right = [];
    col_right = [];
    xiRight = [];
    yiRight = [];
else
    [rowNaNRight, colNaNRight] = find(isnan(xiRight));
    row_right(colNaNRight) = [];
    col_right(colNaNRight) = [];
    xiRight(isnan(xiRight)) = [];
    yiRight(isnan(yiRight)) = [];
end

%------------------------------------------------------------------------------------------------------------------------
% Borde inferior de un rectángulo/cuadrado
[row_bot,col_bot] = find(vy < imY1);
botEdgeVertsX = vx(:, col_bot);
botEdgeVertsY = vy(:, col_bot);
xiBot = []; yiBot = [];

for i = 1:size(botEdgeVertsX,2)
    if mapToolBoxLicense
        [xiBotval, yiBotval] = polyxpoly(botEdgeVertsX(:,i), botEdgeVertsY(:,i), [imX1 imX2], [imY1 imY1]);
    else
        [xiBotval, yiBotval] = linexline(app,botEdgeVertsX(:,i), botEdgeVertsY(:,i), [imX1 imX2], [imY1 imY1], showIntersectionPlot);
    end
    if isempty(xiBotval) || isempty(yiBotval)
        xiBot(i) = NaN; yiBot(i) = NaN; 
    else
        xiBot(i) = xiBotval;  yiBot(i) = yiBotval;
    end
end

if isempty(xiBot) || isempty(yiBot)
    row_bot = [];
    col_bot = [];
    xiBot = [];
    yiBot = [];
else
    [rowNaNBot, colNaNBot] = find(isnan(xiBot));
    row_bot(colNaNBot) = [];
    col_bot(colNaNBot) = [];
    xiBot(isnan(xiBot)) = [];
    yiBot(isnan(yiBot)) = [];
end

%------------------------------------------------------------------------------------------------------------------------
% Borde superior de un rectángulo/cuadrado
[row_top,col_top] = find(vy > imY2);
topEdgeVertsX = vx(:, col_top);
topEdgeVertsY = vy(:, col_top);
xiTop = []; yiTop = [];

for i = 1:size(topEdgeVertsX,2)
    if mapToolBoxLicense
        [xiTopval, yiTopval] = polyxpoly(topEdgeVertsX(:,i), topEdgeVertsY(:,i), [imX1 imX2], [imY2 imY2]);
    else
        [xiTopval, yiTopval] = linexline(app,topEdgeVertsX(:,i), topEdgeVertsY(:,i), [imX1 imX2], [imY2 imY2], showIntersectionPlot);
    end
    if isempty(xiTopval) || isempty(yiTopval)
        xiTop(i) = NaN; yiTop(i) = NaN; 
    else
        xiTop(i) = xiTopval;  yiTop(i) = yiTopval;
    end
end

if isempty(xiTop) || isempty(yiTop)
    row_top = [];
    col_top = [];
    xiTop = [];
    yiTop = [];
else
    [~, colNaNTop] = find(isnan(xiTop));
    row_top(colNaNTop)  = [];
    col_top(colNaNTop)  = [];
    xiTop(isnan(xiTop)) = [];
    yiTop(isnan(yiTop)) = [];
end

%% ------------------------------------------------------------------------------------------------------------------------
% Apilar las matrices en la celda
row2clip = {row_left,row_right,row_top,row_bot};
col2clip = {col_left,col_right,col_top,col_bot};
xyIntersections = {[xiLeft; yiLeft]', [xiRight; yiRight]', [xiTop; yiTop]', [xiBot; yiBot]'};

%% ------------------------------------------------------------------------------------------------------------------------
% Reemplace los valores con intersección/puntos actualizados
for i = 1:length(row2clip)
    for j = 1:length(row2clip{i})
        vx(row2clip{i}(j), col2clip{i}(j)) = xyIntersections{i}(j,1);
        vy(row2clip{i}(j), col2clip{i}(j)) = xyIntersections{i}(j,2);
    end
end

%% ------------------------------------------------------------------------------------------------------------------------
% Descartar fuera de los puntos límite
[rowvX,colvX] = find(vx < imX1 | vx > imX2);
[rowvY,colvY] = find(vy < imY1 | vy > imY2);

% Índices de valores únicos en la columna
[~, indvY] = unique(colvY);
[~, indvX] = unique(colvX);

% Valores duplicados
duplicatevX = colvX(setdiff(1:numel(colvX), indvX));
duplicatevY = colvY(setdiff(1:numel(colvY), indvY));

% Vértices válidos dentro de los límites
vx(:,[duplicatevX;duplicatevY]) = [];
vy(:,[duplicatevX;duplicatevY]) = [];

%% ------------------------------------------------------------------------------------------------------------------------
% Recortar valores de vértices
clip2boundary = 1;
if clip2boundary == 1
    vxClip = min(max(round(vx),1),imX2);
    vyClip = min(max(round(vy),1),imY2);
else
    vxClip = vx;
    vyClip = vy;
end
%% ------------------------------------------------------------------------------------------------------------------------
% Display figure
if showFigure
    fh = figure();
    fh.WindowState = 'maximized';

    ax1 = subplot(3,1,1);
    h1 = voronoi(X,Y);
    axis equal
    title('Diagrama de voronoi Original')
    
    % Dummy image
    I = true(imY2,imX2);
    ax2 = subplot(3,1,2);
    imshow(I)
    hold on
    h2 = voronoi(X,imY2-Y);
    hold off
    axis equal
    title('imagen del diagrama Original')
    
    ax3 = subplot(3,1,3);
    hold on
    rectangle('Position',[imX1 imY1 imX2-imX1 imY2-imY1]')
    for i = 1:size(vxClip,2)
        plot(vxClip(:,i), vyClip(:,i),'Color',rand(1,3), 'LineWidth',3)
    end
    hold (app.grafPrincipal)
    axis equal
    title('Diagrama de voronoi recortada.')

    linkaxes([ax1 ax2 ax3],'xy')
    %-----------------------------
    %Division de areas.
    %De centro a centro, no se debe dividir con celular adyacentes, se
    %intenta dejar las dos del mismo tamaño.
    %-----------------------------


end
end

%--------------------------------------------------------------------------------------------------------
% Funciones auxiliares
%--------------------------------------------------------------------------------------------------------
function [xi,yi] = linexline(app,L1x, L1y, L2x, L2y, showIntersectionPlot)

% Datos
x1 = L1x(1);
y1 = L1y(1);
x2 = L1x(2);
y2 = L1y(2);
x3 = L2x(1);
y3 = L2y(1);
x4 = L2x(2);
y4 = L2y(2);

% Parametros de segmentos de line
u = ((x1-x3)*(y1-y2) - (y1-y3)*(x1-x2)) / ((x1-x2)*(y3-y4)-(y1-y2)*(x3-x4));
t = ((x1-x3)*(y3-y4) - (y1-y3)*(x3-x4)) / ((x1-x2)*(y3-y4)-(y1-y2)*(x3-x4));

% Verifica si existe interseccion, de ser asi se guarda el valor.
if (u >= 0 && u <= 1.0) && (t >= 0 && t <= 1.0)
    xi = ((x3 + u * (x4-x3)) + (x1 + t * (x2-x1))) / 2; 
    yi = ((y3 + u * (y4-y3)) + (y1 + t * (y2-y1))) / 2;
else
    xi = NaN;
    yi = NaN;
end

if showIntersectionPlot
    % Dibujar la lineas
    plot([x1 x2], [y1 y2], 'LineWidth', 3)
    hold on
    plot([x3 x4], [y3 y4], 'LineWidth', 3)
    
    % Dibujar las lineas de interseccion.
    plot(x3 + u * (x4-x3), y3 + u * (y4-y3), 'ro', 'MarkerSize', 15)
    plot(x1 + t * (x2-x1), y1 + t * (y2-y1), 'bo', 'MarkerSize', 15)
    hold off
    xlabel('X'); ylabel('Y') 
    grid on

    ax = gca;
    exportgraphics(ax,'LineXPlot.png')
end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
        % Tamaño del area delimitada 
        imX1 = 0; imX2 = app.Lado1EditField.Value;
        imY1 = 0; imY2 = app.Lado2EditField.Value;
        VoronoiBoundary = [imX1 imX2 imY1 imY2]; % [xmin, xmax, ymin, ymax]
        %Numero de puntos a generar.
        numPoints = app.NumPuntosEditField.Value;

        %Llenado de tabla
        app.UITable2.Data{2,1} = numPoints;   
        %app.UITable2.Data{2,2} =

        
        % Define points for Voronoi diagram
        X = randi([imX1,imX2], 1, numPoints);
        Y = randi([imY1,imY2], 1, numPoints);
        
        % Function callback
        [vxO,vyO,vxClip,vyClip] = CellProliferation(app,X, Y, VoronoiBoundary);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpcionesPanel = uipanel(app.UIFigure);
            app.OpcionesPanel.Title = 'Opciones';
            app.GridLayout = uigridlayout(app.OpcionesPanel);
            app.GraficarButton = uibutton(app.GridLayout, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.LimpiarButton = uibutton(app.GridLayout, 'push');
            app.LimpiarButton.Text = 'Limpiar';
            app.Lado1EditFieldLabel = uilabel(app.GridLayout);
            app.Lado1EditFieldLabel.Text = 'Lado 1';
            app.Lado1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Lado1EditField.ValueDisplayFormat = '%.0f';
            app.Lado2EditFieldLabel = uilabel(app.GridLayout);
            app.Lado2EditFieldLabel.Text = 'Lado 2';
            app.Lado2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Lado2EditField.ValueDisplayFormat = '%.0f';
            app.MaxDivisionesEditFieldLabel = uilabel(app.GridLayout);
            app.MaxDivisionesEditFieldLabel.Text = 'Max Divisiones';
            app.MaxDivisionesEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxDivisionesEditField.ValueDisplayFormat = '%.0f';
            app.NumPuntosEditFieldLabel = uilabel(app.GridLayout);
            app.NumPuntosEditFieldLabel.Text = 'Num Puntos';
            app.NumPuntosEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumPuntosEditField.ValueDisplayFormat = '%.0f';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'Num incial'; 'Num final'};
            app.UITable2.RowName = {};
            app.GraficasPanel = uipanel(app.UIFigure);
            app.GraficasPanel.Title = 'Graficas';
            app.grafPricipal = uiaxes(app.GraficasPanel);
            title(app.grafPricipal, 'Voronoi')
            xlabel(app.grafPricipal, 'X')
            ylabel(app.grafPricipal, 'Y')
            zlabel(app.grafPricipal, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazGrafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end